classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StartRecordingButton        matlab.ui.control.Button
        StartRecordingStatus        matlab.ui.control.Label
        InitialiseSetupButton       matlab.ui.control.Button
        InitialiseSetupStatus       matlab.ui.control.Label
        TabGroupAnalogue            matlab.ui.container.TabGroup
        A1Tab                       matlab.ui.container.Tab
        A1EnableSensor              matlab.ui.control.CheckBox
        aLabel                      matlab.ui.control.Label
        A1EditFieldA                matlab.ui.control.NumericEditField
        bLabel                      matlab.ui.control.Label
        A1EditFieldB                matlab.ui.control.NumericEditField
        A1UnitDropdownA             matlab.ui.control.DropDown
        A1TypeSelect                matlab.ui.container.ButtonGroup
        flowButton                  matlab.ui.control.RadioButton
        pressureButton              matlab.ui.control.RadioButton
        A1UnitDropdownB             matlab.ui.control.DropDown
        A2Tab                       matlab.ui.container.Tab
        A2EnableSensor              matlab.ui.control.CheckBox
        aLabel_2                    matlab.ui.control.Label
        A2EditFieldA                matlab.ui.control.NumericEditField
        bLabel_2                    matlab.ui.control.Label
        A2EditFieldB                matlab.ui.control.NumericEditField
        A2TypeSelect                matlab.ui.container.ButtonGroup
        flowButton_2                matlab.ui.control.RadioButton
        pressureButton_2            matlab.ui.control.RadioButton
        A2UnitDropdownA             matlab.ui.control.DropDown
        A2UnitDropdownB             matlab.ui.control.DropDown
        A3Tab                       matlab.ui.container.Tab
        A3EnableSensor              matlab.ui.control.CheckBox
        aLabel_3                    matlab.ui.control.Label
        A3EditFieldA                matlab.ui.control.NumericEditField
        bLabel_3                    matlab.ui.control.Label
        A3EditFieldB                matlab.ui.control.NumericEditField
        A3TypeSelect                matlab.ui.container.ButtonGroup
        flowButton_3                matlab.ui.control.RadioButton
        pressureButton_3            matlab.ui.control.RadioButton
        A3UnitDropdownA             matlab.ui.control.DropDown
        A3UnitDropdownB             matlab.ui.control.DropDown
        A4Tab                       matlab.ui.container.Tab
        A4EnableSensor              matlab.ui.control.CheckBox
        aLabel_4                    matlab.ui.control.Label
        A4EditFieldA                matlab.ui.control.NumericEditField
        bLabel_4                    matlab.ui.control.Label
        A4EditFieldB                matlab.ui.control.NumericEditField
        A4TypeSelect                matlab.ui.container.ButtonGroup
        flowButton_4                matlab.ui.control.RadioButton
        pressureButton_4            matlab.ui.control.RadioButton
        A4UnitDropdownA             matlab.ui.control.DropDown
        A4UnitDropdownB             matlab.ui.control.DropDown
        A5Tab                       matlab.ui.container.Tab
        A5EnableSensor              matlab.ui.control.CheckBox
        aLabel_5                    matlab.ui.control.Label
        A5EditFieldA                matlab.ui.control.NumericEditField
        bLabel_5                    matlab.ui.control.Label
        A5EditFieldB                matlab.ui.control.NumericEditField
        A5TypeSelect                matlab.ui.container.ButtonGroup
        flowButton_5                matlab.ui.control.RadioButton
        pressureButton_5            matlab.ui.control.RadioButton
        A5UnitDropdownA             matlab.ui.control.DropDown
        A5UnitDropdownB             matlab.ui.control.DropDown
        SelectdeviceLabel           matlab.ui.control.Label
        SelectCOMPort               matlab.ui.control.DropDown
        RefreshCOMPorts             matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        flowGraph                   matlab.ui.control.UIAxes
        pressureGraph               matlab.ui.control.UIAxes
        TabGroupDigital             matlab.ui.container.TabGroup
        D1Tab                       matlab.ui.container.Tab
        D1EnableSensor              matlab.ui.control.CheckBox
        OutputLabel_7               matlab.ui.control.Label
        D1EditFieldOutput           matlab.ui.control.NumericEditField
        D1TypeSelect                matlab.ui.container.ButtonGroup
        flowButton_6                matlab.ui.control.RadioButton
        pressureButton_6            matlab.ui.control.RadioButton
        D1UnitDropdownOutput        matlab.ui.control.DropDown
        D2Tab                       matlab.ui.container.Tab
        D2EnableSensor              matlab.ui.control.CheckBox
        OutputLabel_12              matlab.ui.control.Label
        D2EditFieldOutput           matlab.ui.control.NumericEditField
        D2TypeSelect                matlab.ui.container.ButtonGroup
        flowButton_7                matlab.ui.control.RadioButton
        pressureButton_7            matlab.ui.control.RadioButton
        D2UnitDropdownOutput        matlab.ui.control.DropDown
        D3Tab                       matlab.ui.container.Tab
        D3EnableSensor              matlab.ui.control.CheckBox
        OutputLabel_13              matlab.ui.control.Label
        D3EditFieldOutput           matlab.ui.control.NumericEditField
        D3TypeSelect                matlab.ui.container.ButtonGroup
        flowButton_8                matlab.ui.control.RadioButton
        pressureButton_8            matlab.ui.control.RadioButton
        D3UnitDropdownOutput        matlab.ui.control.DropDown
        D4Tab                       matlab.ui.container.Tab
        D4EnableSensor              matlab.ui.control.CheckBox
        OutputLabel_14              matlab.ui.control.Label
        D4EditFieldOutput           matlab.ui.control.NumericEditField
        D4TypeSelect                matlab.ui.container.ButtonGroup
        flowButton_9                matlab.ui.control.RadioButton
        pressureButton_9            matlab.ui.control.RadioButton
        D4UnitDropdownOutput        matlab.ui.control.DropDown
        D5Tab                       matlab.ui.container.Tab
        D5EnableSensor              matlab.ui.control.CheckBox
        OutputLabel_15              matlab.ui.control.Label
        D5EditFieldOutput           matlab.ui.control.NumericEditField
        D5TypeSelect                matlab.ui.container.ButtonGroup
        flowButton_10               matlab.ui.control.RadioButton
        pressureButton_10           matlab.ui.control.RadioButton
        D5UnitDropdownOutput        matlab.ui.control.DropDown
        TabGroup                    matlab.ui.container.TabGroup
        P1Tab                       matlab.ui.container.Tab
        P1RateUnitsLabel            matlab.ui.control.Label
        FeedbackLabel_4             matlab.ui.control.Label
        P1FeedbackSensor            matlab.ui.control.DropDown
        PumprateSpinnerLabel        matlab.ui.control.Label
        P1RateEditField             matlab.ui.control.Spinner
        P1UpdateButton              matlab.ui.control.Button
        P2Tab                       matlab.ui.container.Tab
        P2RateUnitsLabel            matlab.ui.control.Label
        FeedbackLabel_3             matlab.ui.control.Label
        P2FeedbackSensor            matlab.ui.control.DropDown
        PumprateSpinnerLabel_2      matlab.ui.control.Label
        P2RateEditField             matlab.ui.control.Spinner
        P2UpdateButton              matlab.ui.control.Button
        P3Tab                       matlab.ui.container.Tab
        P3RateUnitsLabel            matlab.ui.control.Label
        FeedbackLabel_2             matlab.ui.control.Label
        P3FeedbackSensor            matlab.ui.control.DropDown
        PumprateSpinnerLabel_3      matlab.ui.control.Label
        P3RateEditField             matlab.ui.control.Spinner
        P3UpdateButton              matlab.ui.control.Button
        P4Tab                       matlab.ui.container.Tab
        P4RateUnitsLabel            matlab.ui.control.Label
        FeedbackLabel               matlab.ui.control.Label
        P4FeedbackSensor            matlab.ui.control.DropDown
        PumprateSpinnerLabel_4      matlab.ui.control.Label
        P4RateEditField             matlab.ui.control.Spinner
        P4UpdateButton              matlab.ui.control.Button
        FilenameEditFieldLabel      matlab.ui.control.Label
        FilenameEditField           matlab.ui.control.EditField
        RenameButton                matlab.ui.control.Button
        LegendA1CheckBox            matlab.ui.control.CheckBox
        LegendA1Colour              matlab.ui.control.Button
        LegendA2Colour              matlab.ui.control.Button
        LegendA2CheckBox            matlab.ui.control.CheckBox
        LegendA3Colour              matlab.ui.control.Button
        LegendA3CheckBox            matlab.ui.control.CheckBox
        LegendA4Colour              matlab.ui.control.Button
        LegendA4CheckBox            matlab.ui.control.CheckBox
        LegendA5Colour              matlab.ui.control.Button
        LegendA5CheckBox            matlab.ui.control.CheckBox
        LegendD1Colour              matlab.ui.control.Button
        LegendD1CheckBox            matlab.ui.control.CheckBox
        LegendD2Colour              matlab.ui.control.Button
        LegendD2CheckBox            matlab.ui.control.CheckBox
        LegendD3Colour              matlab.ui.control.Button
        LegendD3CheckBox            matlab.ui.control.CheckBox
        LegendD4Colour              matlab.ui.control.Button
        LegendD4CheckBox            matlab.ui.control.CheckBox
        LegendD5Colour              matlab.ui.control.Button
        LegendD5CheckBox            matlab.ui.control.CheckBox
        AutoscaleYSwitchLabel       matlab.ui.control.Label
        FlowAutoscaleYSwitch        matlab.ui.control.Switch
        LowerlimitEditFieldLabel    matlab.ui.control.Label
        FlowGraphYLowerLimit        matlab.ui.control.NumericEditField
        UpperlimitEditFieldLabel    matlab.ui.control.Label
        FlowGraphYUpperLimit        matlab.ui.control.NumericEditField
        AutoscaleYSwitchLabel_2     matlab.ui.control.Label
        PressureAutoscaleYSwitch    matlab.ui.control.Switch
        UpperlimitEditFieldLabel_2  matlab.ui.control.Label
        PressureGraphYUpperLimit    matlab.ui.control.NumericEditField
        LowerlimitEditFieldLabel_2  matlab.ui.control.Label
        PressureGraphYLowerLimit    matlab.ui.control.NumericEditField
        Button                      matlab.ui.control.Button
        Button_11                   matlab.ui.control.Button
        CalibratesensorButton       matlab.ui.control.Button
    end


    properties (Access = private)
        serialPortArduino % the serial port to which the arduino is connected.
        plotTimer % timer from which the plots will update.
        sensorData
        pumpDropBoxItems % Items the drop box to select the feedback sensor.
        lastFile
        P1FeedbackSelection; % The item that is currently selected as feedback sensor for pump 1.
        P2FeedbackSelection;
        P3FeedbackSelection;
        P4FeedbackSelection;
        analogueFlowUnits = {'V/L/min', 'V/L/s', ...
                             'V/mL/min', 'V/mL/s', ...
                             'mV/L/min', 'mV/L/s', ...
                             'mV/mL/min', 'mV/mL/s'} % Units to inhabit the drop down menu of analogue flow sensors.
        analoguePressureUnits = {'V/psi', 'mV/psi', ...
                                'V/bar', 'V/mbar', ...
                                'mV/bar', 'mV/mbar', ...
                                'V/mmHg', 'mV/mmHg'} % Units to inhabit the drop down menu of analogue pressure sensors.
        digitalFlowUnits = { 'P/L/min', 'P/L/s',...
                             'P/mL/min', 'P/mL/s'}; % Units to inhabit the drop down menu of digital flow sensors.
        digitalPressureUnits = { 'P/psi', 'P/bar',...
                                 'P/mbar', 'P/mmHg'}; % Units to inhabit the drop down menu of digital pressure sensors.
        Initialised = false; % Description
    end

    methods (Access = private)
        function success = connectToArduino(app, comPort)
            success = true;
            if setSerialPort(comPort)
                if ~openSerialChannel(app)
                    success = false;
                    disp('Failed to open serial channel');
                end
            else
                success = false;
                disp('Failed to set serial port');
            end
        end % connectToArduino
        
        function disableSensorParameterInput(app, sensor)
            switch(sensor)
                case 'A1'
                    app.A1EditFieldB.Enable = 'off';
                    app.A1EditFieldA.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'A1');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'A1');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'A1');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'A1');
                case 'A2'
                    app.A2EditFieldB.Enable = 'off';
                    app.A2EditFieldA.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'A2');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'A2');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'A2');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'A2');
                case 'A3'
                    app.A3EditFieldB.Enable = 'off';
                    app.A3EditFieldA.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'A3');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'A3');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'A3');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'A3');
                case 'A4'
                    app.A4EditFieldB.Enable = 'off';
                    app.A4EditFieldA.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'A4');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'A4');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'A4');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'A4');
                case 'A5'
                    app.A5EditFieldB.Enable = 'off';
                    app.A5EditFieldA.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'A5');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'A5');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'A5');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'A5');
                case 'D1'
                    app.D1EditFieldOutput.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'D1');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'D1');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'D1');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'D1');
                case 'D2'
                    app.D2EditFieldOutput.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'D2');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'D2');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'D2');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'D2');
                case 'D3'
                    app.D3EditFieldOutput.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'D3');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'D3');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'D3');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'D3');
                case 'D4' 
                    app.D4EditFieldOutput.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'D4');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'D4');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'D4');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'D4');
                case 'D5'
                    app.D5EditFieldOutput.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'D5');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'D5');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'D5');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'D5');
            end
        end % disableSensorParameterInput
        
        function enableSensorParameterInput(app, sensor)
            switch(sensor)
                case 'A1'
                    app.A1EditFieldB.Enable = 'on';
                    app.A1EditFieldA.Enable = 'on';
                    if strcmp(app.A1TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'A1');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'A1');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'A1');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'A1');
                    end
                case 'A2'
                    app.A2EditFieldB.Enable = 'on';
                    app.A2EditFieldA.Enable = 'on';
                    if strcmp(app.A2TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'A2');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'A2');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'A2');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'A2');
                    end
                case 'A3'
                    app.A3EditFieldB.Enable = 'on';
                    app.A3EditFieldA.Enable = 'on';
                    if strcmp(app.A3TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'A3');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'A3');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'A3');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'A3');
                    end
                case 'A4'
                    app.A4EditFieldB.Enable = 'on';
                    app.A4EditFieldA.Enable = 'on';
                    if strcmp(app.A4TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'A4');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'A4');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'A4');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'A4');
                    end
                case 'A5'
                    app.A5EditFieldB.Enable = 'on';
                    app.A5EditFieldA.Enable = 'on';
                    if strcmp(app.A5TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'A5');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'A5');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'A5');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'A5');
                    end
                case 'D1'
                    app.D1EditFieldOutput.Enable = 'on';
                    if strcmp(app.D1TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'D1');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'D1');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'D1');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'D1');
                    end
                case 'D2'
                    app.D2EditFieldOutput.Enable = 'on';
                    if strcmp(app.D2TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'D2');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'D2');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'D2');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'D2');
                    end
                case 'D3'
                    app.D3EditFieldOutput.Enable = 'on';
                    if strcmp(app.D3TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'D3');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'D3');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'D3');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'D3');
                    end
                case 'D4'
                    app.D4EditFieldOutput.Enable = 'on';
                    if strcmp(app.D4TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'D4');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'D4');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'D4');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'D4');
                    end
                case 'D5'
                    app.D5EditFieldOutput.Enable = 'on';
                    if strcmp(app.D5TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'D5');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'D5');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'D5');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'D5');
                    end
            end
        end % enableSensorParameterInput
        
        function success = setAnalogueSensorToWorkspace(app, enable, number, aVal, bVal, type)
            success = true;
            if enable
                 if ~setAnalogueSensor(number, aVal, bVal, type)
                     success = false; % only set success to false when a failure occurs.
                 end
            else
                if ~setAnalogueSensor(number, -1, 0, '') % Set output to -1 to indicate disabled sensor.
                    success = false;
                end
            end
        end % setAnalogueSensorToWorkspace
        
        function success = setDigitalSensorToWorkspace(app, enable, number, output, type)
            success = true;
            if enable
                if ~setDigitalSensor(number, output, type)
                    success = false;
                end
            else
                if ~setDigitalSensor(number, -1, '')
                    success = false;
                end                
            end
        end % setDigitalSensorToWorkplace
        
        function success = setSensorParametersToMemory(app)
            success = true;
            if ~setAnalogueSensorToWorkspace(app, app.A1EnableSensor.Value, 1, ...
                    app.A1EditFieldA.Value, app.A1EditFieldB.Value, app.A1TypeSelect.SelectedObject.Text)
                success = false;
            end % A1
            
            if ~setAnalogueSensorToWorkspace(app, app.A2EnableSensor.Value, 2, ...
                    app.A2EditFieldA.Value, app.A2EditFieldB.Value, app.A2TypeSelect.SelectedObject.Text)
                success = false;
            end % A2
            
            if ~setAnalogueSensorToWorkspace(app, app.A3EnableSensor.Value, 3, ...
                    app.A3EditFieldA.Value, app.A3EditFieldB.Value, app.A3TypeSelect.SelectedObject.Text)
                success = false;
            end % A3
            
            if ~setAnalogueSensorToWorkspace(app, app.A4EnableSensor.Value, 4, ...
                    app.A4EditFieldA.Value, app.A4EditFieldB.Value, app.A4TypeSelect.SelectedObject.Text)
                success = false;
            end % A4
            
            if ~setAnalogueSensorToWorkspace(app, app.A5EnableSensor.Value, 5, ...
                    app.A5EditFieldA.Value, app.A5EditFieldB.Value, app.A5TypeSelect.SelectedObject.Text)
                success = false;
            end % A5
            
            if ~setDigitalSensorToWorkspace(app, app.D1EnableSensor.Value, 1,...
                    app.D1EditFieldOutput.Value, app.D1TypeSelect.SelectedObject.Text)
                success = false;
            end % D1
            
            if ~setDigitalSensorToWorkspace(app, app.D2EnableSensor.Value, 2,...
                    app.D2EditFieldOutput.Value, app.D2TypeSelect.SelectedObject.Text)
                success = false;
            end % D2
            
            if ~setDigitalSensorToWorkspace(app, app.D3EnableSensor.Value, 3,...
                    app.D3EditFieldOutput.Value, app.D3TypeSelect.SelectedObject.Text)
                success = false;
            end % D3
            
            if ~setDigitalSensorToWorkspace(app, app.D4EnableSensor.Value, 4,...
                    app.D4EditFieldOutput.Value, app.D4TypeSelect.SelectedObject.Text)
                success = false;
            end % D4
            
            if ~setDigitalSensorToWorkspace(app, app.D5EnableSensor.Value, 5,...
                    app.D5EditFieldOutput.Value, app.D5TypeSelect.SelectedObject.Text)
                success = false;
            end % D5
            
        end % setSensorParametersToMemory
        
        function success = setPumpParametersToMemory(app)
            success = true;
            if ~setPumpToWorkspace(app, 1, app.P1RateEditField.Value, app.P1FeedbackSensor.Value)
                success = false;
            end
            
            if ~setPumpToWorkspace(app, 2, app.P2RateEditField.Value, app.P2FeedbackSensor.Value)
                success = false;
            end
            
            if ~setPumpToWorkspace(app, 3, app.P3RateEditField.Value, app.P3FeedbackSensor.Value)
                success = false;
            end
            
            if ~setPumpToWorkspace(app, 4, app.P4RateEditField.Value, app.P4FeedbackSensor.Value)
                success = false;
            end
        end % setPumpParametersToMemory

        function success = setPumpToWorkspace(app, pumpNumber, pumpVal, feedbackSensor)
            success = true;
            if pumpVal == 0
                if ~setPump(pumpNumber, pumpVal, '')
                    success = false;
                end
            else
                if ~setPump(pumpNumber, pumpVal, feedbackSensor)
                    success = false;
                end
            end
        end % setPumpToWorkspace

        function success = prepareAndSetupForSerialCommunication(app)
            success = true;
            evalin('base', 'sensorData = handleForData;');
            app.sensorData = evalin('base', 'sensorData');
            
            cla(app.flowGraph);
            cla(app.pressureGraph);
            plot(app.flowGraph, (1:10)', zeros(10,5)); % empty graph to initialise.
            plot(app.pressureGraph, (1:10)', zeros(10,5));
            
            if connectToArduino(app, app.SelectCOMPort.Value)
                if strcmp(app.plotTimer.Running, 'off')
                    start(app.plotTimer);
                elseif strcmp(app.plotTimer.Running, 'on')
                    stop(app.plotTimer);
                    start(app.plotTimer);
                else
                    fprintf(2, 'Timer problem.\r\n');
                end
            else
                success = false;
            end
        end % prepareAndSetupForSerialCommunication
        
        function addItemToPumpDropBox(app, pumpDropdown, sensor)
            tempCell = pumpDropdown.Items;
            if isempty(find(strcmp(tempCell, sensor))) % only add when it is not added yet.
                tempCell{size(tempCell,2)+1} = sensor;
                pumpDropdown.Items = tempCell;
            end
        end % addITemToPumpDropBox
        
        function removeItemFromPumpDropBox(app, pumpDropdown, sensor)
            tempCell = pumpDropdown.Items;
            index = find(strcmp(tempCell, sensor));
            if ~isempty(index)
                tempCell{index}=[];
                goodVals = ~cellfun('isempty', tempCell);
                tempCell = tempCell(goodVals);
            end
            pumpDropdown.Items = tempCell;
        end % removeItemFromPumpDropBox

        function addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor)
            if strcmp(selectedButton, 'flow')
                addItemToPumpDropBox(app, app.P1FeedbackSensor, sensor);
                addItemToPumpDropBox(app, app.P2FeedbackSensor, sensor);
                addItemToPumpDropBox(app, app.P3FeedbackSensor, sensor);
                addItemToPumpDropBox(app, app.P4FeedbackSensor, sensor);
            elseif strcmp(selectedButton, 'pressure')
                removeItemFromPumpDropBox(app, app.P1FeedbackSensor, sensor);
                removeItemFromPumpDropBox(app, app.P2FeedbackSensor, sensor);
                removeItemFromPumpDropBox(app, app.P3FeedbackSensor, sensor);
                removeItemFromPumpDropBox(app, app.P4FeedbackSensor, sensor);
            else
                fprintf(2, '%s type select unrecognised.', sensor);
            end
        end % addOrRemoveItemToPumpDropBoxes
        
        function enableDisableLineFromLivePlot(app, sensor, status)
            switch sensor
                case 'A1'
                    sensorIndex = 1;
                case 'A2'
                    sensorIndex = 2;
                case 'A3'
                    sensorIndex = 3;
                case 'A4'
                    sensorIndex = 4;
                case 'A5'
                    sensorIndex = 5;
                case 'D1'
                    sensorIndex = 6;
                case 'D2'
                    sensorIndex = 7;
                case 'D3'
                    sensorIndex = 8;
                case 'D4'
                    sensorIndex = 9;
                case 'D5'
                    sensorIndex = 10;
            end
            tempString = sprintf('plotLines(%d) = %d;', sensorIndex, status);
            evalin('base', tempString);
        end % enableDisableLineFromLivePlot
        
        function convertAnalogueFlowUnits(app, sensorInput, sensorDropdown)
            value = sensorInput.Value;
            switch sensorDropdown.Value
                case {'V/L/min', 'mV/mL/min'}
                    % No conversion required. mV and mL compensate each other. 
                case {'V/L/s', 'mV/mL/s'}
                    sensorInput.Value = (1/60) * value; % mV and mL compensate each other.
                case 'V/mL/min'
                    sensorInput.Value = 1000 * value;
                case 'V/mL/s'
                    sensorInput.Value = (1000/60) * value;
                case 'mV/L/min'
                    sensorInput.Value = (1/1000) * value;
                case 'mV/L/s'
                    sensorInput.Value = (1/60000) * value;                  
            end
            sensorDropdown.Value = 'V/L/min';
        end % convertAnalogueFlowUnits
        
        function convertAnaloguePressureUnits(app, sensorInput, sensorDropdown)
            value = sensorInput.Value;
            switch sensorDropdown.Value
                case 'V/psi'
                    sensorInput.Value = (1/51.7149326) * value;
                case 'mV/psi'
                    sensorInput.Value = (1/51714.9326) * value;
                case {'V/bar', 'mV/mbar'}
                    sensorInput.Value = (1/750.061683) * value; % mV and mbar compensate each other.
                case 'V/mbar'
                    sensorInput.Value = (1000/750.061683) * value;
                case 'mV/bar'
                    sensorInput.Value = (1/750061.683) * value;
                case 'V/mmHg'
                    % No conversion required.
                case 'mV/mmHg'
                    sensorInput.Value = (1/1000) * value;
            end
            sensorDropdown.Value = 'V/mmHg';
        end % convertAnaloguePressureUnit
        
        function convertDigitalFlowUnits(app, sensorInput, sensorDropdown)
            value = sensorInput.Value;
            switch sensorDropdown.Value
                case 'P/L/min'
                    % No conversion required.
                case 'P/L/s'
                    sensorInput.Value = (1/60) * value;
                case 'P/mL/min'
                    sensorInput.Value = 1000 * value;
                case 'P/mL/s'
                    sensorInput.Value = (1000/60) * value;
            end
            sensorDropdown.Value = 'P/L/min';
        end % convertDigitalFlowUnits
        
        function convertDigitalPressureUnits(app, sensorInput, sensorDropdown)
            value = sensorInput.Value;
            switch sensorDropdown.Value
                case 'P/psi'
                    sensorInput.Value = (1/51.7149326) * value;
                case 'P/bar'
                    sensorInput.Value = (1/750.061683) * value;
                case 'P/mbar'
                    sensorInput.Value = (1000/750.061683) * value;
                case 'P/mmHg'
                    % No conversion required.
            end
            sensorDropdown.Value = 'P/mmHg';
        end % convertDigitalPressureUnits
        
        function convertVoltage(app, sensorInput, sensorDropdown)
            value = sensorInput.Value;
            switch sensorDropdown.Value
                case 'V'
                    % No conversion required.
                case 'mV'
                    sensorInput.Value = (1/1000) * value;
            end
            sensorDropdown.Value = 'V';
        end % convertOutputVoltage
        
        function convertUnits(app)
            convertAnalogueSensorUnits(app, app.A1TypeSelect, ...
                                       app.A1EditFieldA, app.A1UnitDropdownA,...
                                       app.A1EditFieldB, app.A1UnitDropdownB);
            convertAnalogueSensorUnits(app, app.A2TypeSelect, ...
                                       app.A2EditFieldA, app.A2UnitDropdownA,...
                                       app.A2EditFieldB, app.A2UnitDropdownB);
            convertAnalogueSensorUnits(app, app.A3TypeSelect, ...
                                       app.A3EditFieldA, app.A3UnitDropdownA,...
                                       app.A3EditFieldB, app.A3UnitDropdownB);
            convertAnalogueSensorUnits(app, app.A4TypeSelect, ...
                                       app.A4EditFieldA, app.A4UnitDropdownA,...
                                       app.A4EditFieldB, app.A4UnitDropdownB);
            convertAnalogueSensorUnits(app, app.A5TypeSelect, ...
                                       app.A5EditFieldA, app.A5UnitDropdownA,...
                                       app.A5EditFieldB, app.A5UnitDropdownB);
            convertDigitalSensorUnits(app, app.D1TypeSelect, app.D1EditFieldOutput, app.D1UnitDropdownOutput);
            convertDigitalSensorUnits(app, app.D2TypeSelect, app.D2EditFieldOutput, app.D2UnitDropdownOutput);
            convertDigitalSensorUnits(app, app.D3TypeSelect, app.D3EditFieldOutput, app.D3UnitDropdownOutput);
            convertDigitalSensorUnits(app, app.D4TypeSelect, app.D4EditFieldOutput, app.D4UnitDropdownOutput);
            convertDigitalSensorUnits(app, app.D5TypeSelect, app.D5EditFieldOutput, app.D5UnitDropdownOutput);
                                   
        end % convertUnits
        
        function convertAnalogueSensorUnits(app, type, ...
                                            aInput, aDropdown, ...
                                            bInput, bDropdown)
            convertVoltage(app, bInput, bDropdown);
            if strcmp(type.SelectedObject.Text, 'flow')
                convertAnalogueFlowUnits(app, aInput, aDropdown);
            elseif strcmp(type.SelectedObject.Text, 'pressure')
                convertAnaloguePressureUnits(app, aInput, aDropdown);
            else
                warning('Unknown type when trying to convert sensor units.')
            end
        end % convertAnalogueSensorUnits
        
        function convertDigitalSensorUnits(app, type, outputPInput, outputPdropdown)
            if strcmp(type.SelectedObject.Text, 'flow')
                convertDigitalFlowUnits(app, outputPInput, outputPdropdown)
            elseif strcmp(type.SelectedObject.Text, 'pressure')
                convertDigitalPressureUnits(app, outputPInput, outputPdropdown)
            else
                warning('Unknown type when trying to convert sensor units.');
            end
        end % convertDigitalSensorUnits

        function [success, activatedSensor] = numberOfSensorsActivated(app)
            success = true;
            sumEnabled = 0;
            activatedSensor = '';
            if app.A1EnableSensor.Value
                sumEnabled = sumEnabled + 1;
                activatedSensor = 'A1';
            end
            if app.A2EnableSensor.Value
                sumEnabled = sumEnabled + 1;
                activatedSensor = 'A2';
            end
            if app.A3EnableSensor.Value
                sumEnabled = sumEnabled + 1;
                activatedSensor = 'A3';
            end
            if app.A4EnableSensor.Value
                sumEnabled = sumEnabled + 1;
                activatedSensor = 'A4';
            end
            if app.A5EnableSensor.Value
                sumEnabled = sumEnabled + 1;
                activatedSensor = 'A5';
            end
            if app.D1EnableSensor.Value
                sumEnabled = sumEnabled + 1;
                activatedSensor = 'D1';
            end
            if app.D2EnableSensor.Value
                sumEnabled = sumEnabled + 1;
                activatedSensor = 'D2';
            end
            if app.D3EnableSensor.Value
                sumEnabled = sumEnabled + 1;
                activatedSensor = 'D3';
            end
            if app.D4EnableSensor.Value
                sumEnabled = sumEnabled + 1;
                activatedSensor = 'D4';
            end
            if app.D5EnableSensor.Value
                sumEnabled = sumEnabled + 1;
                activatedSensor = 'D5';
            end
                     
            if sumEnabled < 1
                warning('No sensor selected.');
                success = false;
            elseif sumEnabled > 1
                warning('Too many sensors selected.');
                success = false;
            end
        end % numberOfSensorsActivated
        
    end % functions


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartRecordingStatus.Visible = 'off';
            app.InitialiseSetupStatus.Visible = 'off';
            assignin('base', 'app', app);
            
            disp('Setting up timer');
            %% Start timer when arduino is successfully connected.
            app.plotTimer = timer('Period', 0.1, ... % Timer that fires every 100ms.
                'ExecutionMode', 'fixedRate',... % always fire every 100ms, timer resets immediately after function call.
                'TasksToExecute', inf); 
            app.plotTimer.TimerFcn = {@livePlot, app}; 
            app.plotTimer.StartFcn = @(~,~)disp('Starting timer.');
            app.plotTimer.StopFcn = @(~,~)disp('Stopping timer.');
            
            evalin('base', 'plotLines = ones(1,10);'); % Initially, plot all lines.
            
            app.StartRecordingButton.Visible = 'off';
            assignin('base', 'calib', false);
            
            clear functions;
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            success = true;
            
            app.lastFile = strcat(datestr(now, 'yyyy-mm-dd_HHMMSS'), '_', app.FilenameEditField.Value,'.csv');
            tempString = strcat('outFile = fopen(''Output/', app.lastFile, ''',''w'');');
            evalin('base', tempString);
            
            outFile = evalin('base', 'outFile');
            if outFile >= 3
                fprintf(outFile, 'Timestamp,row,A1,A2,A3,A4,A5,D1,D2,D3,D4,D5\r\n');
            else
                warning('Could not write header to file.');
                success = false;
            end
            
            app.StartRecordingStatus.Visible = 'on';
            if success
                app.StartRecordingStatus.Text = 'Success!';
                app.RenameButton.Visible = 'off';
                app.StartRecordingButton.Enable = 'off';
            else
                app.StartRecordingStatus.Text = 'Failed!';
            end 
        end

        % Value changed function: A1EnableSensor
        function A1EnableSensorValueChanged(app, event)
            if app.A1EnableSensor.Value
                enableSensorParameterInput(app, 'A1');
            else
                disableSensorParameterInput(app, 'A1');
            end
        end

        % Value changed function: A2EnableSensor
        function A2EnableSensorValueChanged(app, event)
            if app.A2EnableSensor.Value
                enableSensorParameterInput(app, 'A2');
            else
                disableSensorParameterInput(app, 'A2');
            end
        end

        % Button pushed function: InitialiseSetupButton
        function InitialiseSetupButtonPushed(app, event)
            success = true;
            
            app.InitialiseSetupButton.Enable = 'off';
            
            convertUnits(app);
            
            if ~setSensorParametersToMemory(app)
                success = false;
            end
            
            if ~setPumpParametersToMemory(app)
                success = false;
            end

            if success % only start the communication when everything if okay.
                if ~prepareAndSetupForSerialCommunication(app)
                    success = false;
                end
            end
            
            app.InitialiseSetupStatus.Visible = 'on';
            app.InitialiseSetupButton.Enable = 'on';
            if success
                app.InitialiseSetupStatus.Text = 'Success!';
                app.StartRecordingButton.Visible = 'on';
                app.InitialiseSetupButton.Visible = 'off';
                app.Initialised = true;
            else
                app.InitialiseSetupStatus.Text = 'Failed!';
            end 
        end

        % Button pushed function: RefreshCOMPorts
        function RefreshCOMPortsButtonPushed(app, event)
            serialDevices = seriallist;
            numberOfDevices = size(serialDevices,2);
            if numberOfDevices < 1
                app.SelectCOMPort.Items = {'No devices'};
            else
                for i = 1:numberOfDevices
                   tempCell{i} = convertStringsToChars(serialDevices(i));
                end
                app.SelectCOMPort.Items = tempCell;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.RenameButton.Visible = 'on';
            app.StartRecordingButton.Visible = 'off';
            app.InitialiseSetupButton.Visible = 'on';
            app.StartRecordingButton.Enable = 'on';
            
            status = evalin('base', 'exist(''arduinoSerial'')');
            if status % Close if the channel has been created.
                if evalin('base', 'isvalid(arduinoSerial)')
                    try
                        sendArduinoResetSignal();
                    catch
                        warning('Cannot send arduino reset signal due to unknown reason.');
                    end
                    evalin('base', 'fclose(arduinoSerial)'); % Close the channel that communicates with Arduino, can still open afterwards.
                    evalin('base', 'delete(arduinoSerial)'); % Releases the channel such that the COM port can be used again.
                end
                evalin('base', 'clear(''arduinoSerial'')'); % Removes the variable from the workspace since it cannot be opened again after delete.
            end
            
            status = evalin('base', 'exist(''outFile'');');
            if status
                % outFile exists and is opened.
                evalin('base', 'fclose(outFile);');
                evalin('base', 'clear(''outFile'');');
                status = evalin('base', 'exist(''sensorData'');');
                if status
                    evalin('base', 'clear(''sensorData'');');
                end
            end
            
            if strcmp(app.plotTimer.Running, 'on')
                stop(app.plotTimer);
            end     
            assignin('base', 'calib', false);
            app.Initialised = false;
            clear functions; % clear persistent data in functions.
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            StopButtonPushed(app, event);
            delete(app)
        end

        % Value changed function: A3EnableSensor
        function A3EnableSensorValueChanged(app, event)
            if app.A3EnableSensor.Value
                enableSensorParameterInput(app, 'A3');
            else
                disableSensorParameterInput(app, 'A3');
            end
        end

        % Value changed function: A4EnableSensor
        function A4EnableSensorValueChanged(app, event)
            if app.A4EnableSensor.Value
                enableSensorParameterInput(app, 'A4');
            else
                disableSensorParameterInput(app, 'A4');
            end
        end

        % Value changed function: A5EnableSensor
        function A5EnableSensorValueChanged(app, event)
            if app.A5EnableSensor.Value
                enableSensorParameterInput(app, 'A5');
            else
                disableSensorParameterInput(app, 'A5');
            end
        end

        % Value changed function: D1EnableSensor
        function D1EnableSensorValueChanged(app, event)
            if app.D1EnableSensor.Value
                enableSensorParameterInput(app, 'D1');
            else
                disableSensorParameterInput(app, 'D1');
            end
        end

        % Value changed function: D2EnableSensor
        function D2EnableSensorValueChanged(app, event)
            if app.D2EnableSensor.Value
                enableSensorParameterInput(app, 'D2');
            else
                disableSensorParameterInput(app, 'D2');
            end
        end

        % Value changed function: D3EnableSensor
        function D3EnableSensorValueChanged(app, event)
            if app.D3EnableSensor.Value
                enableSensorParameterInput(app, 'D3');
            else
                disableSensorParameterInput(app, 'D3');
            end
        end

        % Value changed function: D4EnableSensor
        function D4EnableSensorValueChanged(app, event)
            if app.D4EnableSensor.Value
                enableSensorParameterInput(app, 'D4');
            else
                disableSensorParameterInput(app, 'D4');
            end
        end

        % Value changed function: D5EnableSensor
        function D5EnableSensorValueChanged(app, event)
            if app.D5EnableSensor.Value
                enableSensorParameterInput(app, 'D5');
            else
                disableSensorParameterInput(app, 'D5');
            end
        end

        % Selection changed function: A1TypeSelect
        function A1TypeSelectSelectionChanged(app, event)
            selectedButton = app.A1TypeSelect.SelectedObject.Text;
            sensor = 'A1';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
            
            if strcmp(selectedButton, 'flow')
                app.A1UnitDropdownA.Items = app.analogueFlowUnits;
            elseif strcmp(selectedButton, 'pressure')
                app.A1UnitDropdownA.Items = app.analoguePressureUnits;
            else
                warning('Unknown sensor A1 type.');
            end
        end

        % Selection changed function: A2TypeSelect
        function A2TypeSelectSelectionChanged(app, event)
            selectedButton = app.A2TypeSelect.SelectedObject.Text;
            sensor = 'A2';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
            
            if strcmp(selectedButton, 'flow')
                app.A2UnitDropdownA.Items = app.analogueFlowUnits;
            elseif strcmp(selectedButton, 'pressure')
                app.A2UnitDropdownA.Items = app.analoguePressureUnits;
            else
                warning('Unknown sensor A2 type.');
            end
        end

        % Selection changed function: A3TypeSelect
        function A3TypeSelectSelectionChanged(app, event)
            selectedButton = app.A3TypeSelect.SelectedObject.Text;
            sensor = 'A3';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
            
            if strcmp(selectedButton, 'flow')
                app.A3UnitDropdownA.Items = app.analogueFlowUnits;
            elseif strcmp(selectedButton, 'pressure')
                app.A3UnitDropdownA.Items = app.analoguePressureUnits;
            else
                warning('Unknown sensor A3 type.');
            end
        end

        % Selection changed function: A4TypeSelect
        function A4TypeSelectSelectionChanged(app, event)
            selectedButton = app.A4TypeSelect.SelectedObject.Text;
            sensor = 'A4';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
            
            if strcmp(selectedButton, 'flow')
                app.A4UnitDropdownA.Items = app.analogueFlowUnits;
            elseif strcmp(selectedButton, 'pressure')
                app.A4UnitDropdownA.Items = app.analoguePressureUnits;
            else
                warning('Unknown sensor A4 type.');
            end
        end

        % Selection changed function: A5TypeSelect
        function A5TypeSelectSelectionChanged(app, event)
            selectedButton = app.A5TypeSelect.SelectedObject.Text;
            sensor = 'A5';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
            
            if strcmp(selectedButton, 'flow')
                app.A5UnitDropdownA.Items = app.analogueFlowUnits;
            elseif strcmp(selectedButton, 'pressure')
                app.A5UnitDropdownA.Items = app.analoguePressureUnits;
            else
                warning('Unknown sensor A5 type.');
            end
        end

        % Selection changed function: D1TypeSelect
        function D1TypeSelectSelectionChanged(app, event)
            selectedButton = app.D1TypeSelect.SelectedObject.Text;
            sensor = 'D1';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
            
            if strcmp(selectedButton, 'flow')
                app.D1UnitDropdownOutput.Items = app.digitalFlowUnits;
            elseif strcmp(selectedButton, 'pressure')
                app.D1UnitDropdownOutput.Items = app.digitalPressureUnits;
            else
                warning('Unknown sensor D1 type.');
            end
        end

        % Selection changed function: D2TypeSelect
        function D2TypeSelectSelectionChanged(app, event)
            selectedButton = app.D2TypeSelect.SelectedObject.Text;
            sensor = 'D2';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
            
            if strcmp(selectedButton, 'flow')
                app.D2UnitDropdownOutput.Items = app.digitalFlowUnits;
            elseif strcmp(selectedButton, 'pressure')
                app.D2UnitDropdownOutput.Items = app.digitalPressureUnits;
            else
                warning('Unknown sensor D2 type.');
            end
        end

        % Selection changed function: D3TypeSelect
        function D3TypeSelectSelectionChanged(app, event)
            selectedButton = app.D3TypeSelect.SelectedObject.Text;
            sensor = 'D3';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
            
            if strcmp(selectedButton, 'flow')
                app.D3UnitDropdownOutput.Items = app.digitalFlowUnits;
            elseif strcmp(selectedButton, 'pressure')
                app.D3UnitDropdownOutput.Items = app.digitalPressureUnits;
            else
                warning('Unknown sensor D3 type.');
            end
        end

        % Selection changed function: D4TypeSelect
        function D4TypeSelectSelectionChanged(app, event)
            selectedButton = app.D4TypeSelect.SelectedObject.Text;
            sensor = 'D4';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
            
            if strcmp(selectedButton, 'flow')
                app.D4UnitDropdownOutput.Items = app.digitalFlowUnits;
            elseif strcmp(selectedButton, 'pressure')
                app.D4UnitDropdownOutput.Items = app.digitalPressureUnits;
            else
                warning('Unknown sensor D4 type.');
            end
        end

        % Selection changed function: D5TypeSelect
        function D5TypeSelectSelectionChanged(app, event)
            selectedButton = app.D5TypeSelect.SelectedObject.Text;
            sensor = 'D5';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
            
            if strcmp(selectedButton, 'flow')
                app.D5UnitDropdownOutput.Items = app.digitalFlowUnits;
            elseif strcmp(selectedButton, 'pressure')
                app.D5UnitDropdownOutput.Items = app.digitalPressureUnits;
            else
                warning('Unknown sensor D5 type.');
            end
        end

        % Button pushed function: RenameButton
        function RenameButtonPushed(app, event)
            if ~isempty(app.lastFile)
                oldFile = strcat('Output/', app.lastFile);
                newFile = strrep(app.lastFile, ...
                    app.lastFile(19:strfind(app.lastFile, '.')-1), ...
                    app.FilenameEditField.Value);
                moveToFile = strcat('Output/', newFile);
                if strcmp(oldFile, moveToFile)
                    warning('Cannot rename the file, names are the same.');
                else
                    movefile(oldFile, moveToFile);
                end
                app.lastFile = newFile;
            else
                warning('No previous measurement performed.');
            end
        end

        % Value changed function: LegendA1CheckBox
        function LegendA1CheckBoxValueChanged(app, event)
            enable = app.LegendA1CheckBox.Value;
            enableDisableLineFromLivePlot(app, 'A1', enable);
        end

        % Value changed function: LegendA2CheckBox
        function LegendA2CheckBoxValueChanged(app, event)
            enable = app.LegendA2CheckBox.Value;
            enableDisableLineFromLivePlot(app, 'A2', enable);
        end

        % Value changed function: LegendA3CheckBox
        function LegendA3CheckBoxValueChanged(app, event)
            enable = app.LegendA3CheckBox.Value;
            enableDisableLineFromLivePlot(app, 'A3', enable);
        end

        % Value changed function: LegendA4CheckBox
        function LegendA4CheckBoxValueChanged(app, event)
            enable = app.LegendA4CheckBox.Value;
            enableDisableLineFromLivePlot(app, 'A4', enable);
        end

        % Value changed function: LegendA5CheckBox
        function LegendA5CheckBoxValueChanged(app, event)
            enable = app.LegendA5CheckBox.Value;
            enableDisableLineFromLivePlot(app, 'A5', enable);
        end

        % Value changed function: LegendD1CheckBox
        function LegendD1CheckBoxValueChanged(app, event)
            enable = app.LegendD1CheckBox.Value;
            enableDisableLineFromLivePlot(app, 'D1', enable);
        end

        % Value changed function: LegendD2CheckBox
        function LegendD2CheckBoxValueChanged(app, event)
            enable = app.LegendD2CheckBox.Value;
            enableDisableLineFromLivePlot(app, 'D2', enable);
        end

        % Value changed function: LegendD3CheckBox
        function LegendD3CheckBoxValueChanged(app, event)
            enable = app.LegendD3CheckBox.Value;
            enableDisableLineFromLivePlot(app, 'D3', enable);
        end

        % Value changed function: LegendD4CheckBox
        function LegendD4CheckBoxValueChanged(app, event)
            enable = app.LegendD4CheckBox.Value;
            enableDisableLineFromLivePlot(app, 'D4', enable);
        end

        % Value changed function: LegendD5CheckBox
        function LegendD5CheckBoxValueChanged(app, event)
            enable = app.LegendD5CheckBox.Value;
            enableDisableLineFromLivePlot(app, 'D5', enable);
        end

        % Value changed function: FlowAutoscaleYSwitch
        function FlowAutoscaleYSwitchValueChanged(app, event)
            value = app.FlowAutoscaleYSwitch.Value;
            if strcmp(value, 'Yes')
                ylim(app.flowGraph, 'auto');
                app.FlowGraphYLowerLimit.Enable = 'off';
                app.FlowGraphYUpperLimit.Enable = 'off';
            elseif strcmp(value, 'No')
                app.FlowGraphYLowerLimit.Enable = 'on';
                app.FlowGraphYUpperLimit.Enable = 'on';
                app.FlowGraphYLowerLimit.Value = app.flowGraph.YLim(1);
                app.FlowGraphYUpperLimit.Value = app.flowGraph.YLim(2);
                ylim(app.flowGraph, 'manual');
                app.flowGraph.YLim = [app.FlowGraphYLowerLimit.Value, app.FlowGraphYUpperLimit.Value];
            else
                warning('Unknown flow autoscale switch mode.');
            end
        end

        % Value changed function: FlowGraphYUpperLimit
        function FlowGraphYUpperLimitValueChanged(app, event)
            value = app.FlowGraphYUpperLimit.Value;
            if value <= app.flowGraph.YLim(1)
                warning('Upper limit smaller than lower limit.');
            else
                app.flowGraph.YLim(2) = value;
            end
        end

        % Value changed function: FlowGraphYLowerLimit
        function FlowGraphYLowerLimitValueChanged(app, event)
            value = app.FlowGraphYLowerLimit.Value;
            if value >= app.flowGraph.YLim(2)
                warning('Lower limit exceeds upper limit.')
            else
                app.flowGraph.YLim(1) = value;
            end
        end

        % Value changed function: PressureAutoscaleYSwitch
        function PressureAutoscaleYSwitchValueChanged(app, event)
            value = app.PressureAutoscaleYSwitch.Value;
            if strcmp(value, 'Yes')
                ylim(app.pressureGraph, 'auto');
                app.PressureGraphYLowerLimit.Enable = 'off';
                app.PressureGraphYUpperLimit.Enable = 'off';
            elseif strcmp(value, 'No')
                app.PressureGraphYLowerLimit.Enable = 'on';
                app.PressureGraphYUpperLimit.Enable = 'on';
                app.PressureGraphYLowerLimit.Value = app.pressureGraph.YLim(1);
                app.PressureGraphYUpperLimit.Value = app.pressureGraph.YLim(2);
                ylim(app.pressureGraph, 'manual');
                app.pressureGraph.YLim = [app.PressureGraphYLowerLimit.Value, app.PressureGraphYUpperLimit.Value];
            else
                warning('Unknown flow autoscale switch mode.');
            end
        end

        % Value changed function: PressureGraphYLowerLimit
        function PressureGraphYLowerLimitValueChanged(app, event)
            value = app.PressureGraphYLowerLimit.Value;
            if value >= app.pressureGraph.YLim(2)
                warning('Lower limit exceeds upper limit.');
            else
                app.pressureGraph.YLim(1) = value;
            end
        end

        % Value changed function: PressureGraphYUpperLimit
        function PressureGraphYUpperLimitValueChanged(app, event)
            value = app.PressureGraphYUpperLimit.Value;
            if value <= app.pressureGraph.YLim(1)
                warning('Upper limit smaller than lower limit.');
            else
                app.pressureGraph.YLim(2) = value;
            end
        end

        % Value changed function: P1FeedbackSensor
        function P1FeedbackSensorValueChanged(app, event)
            value = app.P1FeedbackSensor.Value;
            if isempty(app.P1FeedbackSelection)
                app.P1FeedbackSelection = value;
                if ~strcmp(value, 'Please select') % Only remove from others if an actual sensor is selected.
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, value);
                end
            else
                % If previous selection was a sensor, add it to other drop down menus.
                if ~strcmp(app.P1FeedbackSelection, 'Please select')
                    addItemToPumpDropBox(app, app.P2FeedbackSensor, app.P1FeedbackSelection);
                    addItemToPumpDropBox(app, app.P3FeedbackSensor, app.P1FeedbackSelection);
                    addItemToPumpDropBox(app, app.P4FeedbackSensor, app.P1FeedbackSelection);
                end
                
                app.P1FeedbackSelection = value;
                if ~strcmp(value, 'Please select')
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, value);
                end
            end
        end

        % Value changed function: P2FeedbackSensor
        function P2FeedbackSensorValueChanged(app, event)
            value = app.P2FeedbackSensor.Value;
            if isempty(app.P2FeedbackSelection)
                app.P2FeedbackSelection = value;
                if ~strcmp(value, 'Please select') % Only remove from others if an actual sensor is selected.
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, value);
                end
            else
                % If previous selection was a sensor, add it to other drop down menus.
                if ~strcmp(app.P2FeedbackSelection, 'Please select')
                    addItemToPumpDropBox(app, app.P1FeedbackSensor, app.P2FeedbackSelection);
                    addItemToPumpDropBox(app, app.P3FeedbackSensor, app.P2FeedbackSelection);
                    addItemToPumpDropBox(app, app.P4FeedbackSensor, app.P2FeedbackSelection);
                end
                
                app.P2FeedbackSelection = value;
                if ~strcmp(value, 'Please select')
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, value);
                end
            end
        end

        % Value changed function: P3FeedbackSensor
        function P3FeedbackSensorValueChanged(app, event)
            value = app.P3FeedbackSensor.Value;
            if isempty(app.P3FeedbackSelection)
                app.P3FeedbackSelection = value;
                if ~strcmp(value, 'Please select') % Only remove from others if an actual sensor is selected.
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, value);
                end
            else
                % If previous selection was a sensor, add it to other drop down menus.
                if ~strcmp(app.P3FeedbackSelection, 'Please select')
                    addItemToPumpDropBox(app, app.P1FeedbackSensor, app.P3FeedbackSelection);
                    addItemToPumpDropBox(app, app.P2FeedbackSensor, app.P3FeedbackSelection);
                    addItemToPumpDropBox(app, app.P4FeedbackSensor, app.P3FeedbackSelection);
                end
                
                app.P3FeedbackSelection = value;
                if ~strcmp(value, 'Please select')
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, value);
                end
            end
        end

        % Value changed function: P4FeedbackSensor
        function P4FeedbackSensorValueChanged(app, event)
            value = app.P4FeedbackSensor.Value;
            if isempty(app.P4FeedbackSelection)
                app.P4FeedbackSelection = value;
                if ~strcmp(value, 'Please select') % Only remove from others if an actual sensor is selected.
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, value);
                end
            else
                % If previous selection was a sensor, add it to other drop down menus.
                if ~strcmp(app.P4FeedbackSelection, 'Please select')
                    addItemToPumpDropBox(app, app.P1FeedbackSensor, app.P4FeedbackSelection);
                    addItemToPumpDropBox(app, app.P2FeedbackSensor, app.P4FeedbackSelection);
                    addItemToPumpDropBox(app, app.P3FeedbackSensor, app.P4FeedbackSelection);
                end
                
                app.P4FeedbackSelection = value;
                if ~strcmp(value, 'Please select')
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, value);
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, value);
                end
            end
        end

        % Button pushed function: P1UpdateButton
        function P1UpdateButtonPushed(app, event)
            updatePumpRate(1, app.P1RateEditField.Value, app.P1FeedbackSensor.Value);
        end

        % Button pushed function: P2UpdateButton
        function P2UpdateButtonPushed(app, event)
            updatePumpRate(2, app.P2RateEditField.Value, app.P2FeedbackSensor.Value);
        end

        % Button pushed function: P3UpdateButton
        function P3UpdateButtonPushed(app, event)
            updatePumpRate(3, app.P3RateEditField.Value, app.P3FeedbackSensor.Value);
        end

        % Button pushed function: P4UpdateButton
        function P4UpdateButtonPushed(app, event)
            updatePumpRate(4, app.P4RateEditField.Value, app.P4FeedbackSensor.Value);
        end

        % Button pushed function: CalibratesensorButton
        function CalibratesensorButtonPushed(app, event)
            [success, activatedSensor] = numberOfSensorsActivated(app);
            if success
                assignin('base', 'calib', true);
                app.FilenameEditField.Value = strcat('sensorCalibration_', ...
                                                      activatedSensor);
                InitialiseSetupButtonPushed(app, event);
                if app.Initialised
                    StartRecordingButtonPushed(app, event);
                else
                    warning('Could not initialise set-up.');
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start recording';
            app.StartRecordingStatus = uilabel(app.UIFigure);
            app.StartRecordingStatus.Text = 'Success!';
            app.InitialiseSetupButton = uibutton(app.UIFigure, 'push');
            app.InitialiseSetupButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseSetupButtonPushed, true);
            app.InitialiseSetupButton.Text = 'Initialise set-up';
            app.InitialiseSetupStatus = uilabel(app.UIFigure);
            app.InitialiseSetupStatus.Text = 'Success!';
            app.TabGroupAnalogue = uitabgroup(app.UIFigure);
            app.A1Tab = uitab(app.TabGroupAnalogue);
            app.A1Tab.Title = 'A1';
            app.A1EnableSensor = uicheckbox(app.A1Tab);
            app.A1EnableSensor.ValueChangedFcn = createCallbackFcn(app, @A1EnableSensorValueChanged, true);
            app.A1EnableSensor.Text = 'Enable';
            app.aLabel = uilabel(app.A1Tab);
            app.aLabel.Enable = 'off';
            app.aLabel.Text = 'a:';
            app.A1EditFieldA = uieditfield(app.A1Tab, 'numeric');
            app.A1EditFieldA.Limits = [0 Inf];
            app.A1EditFieldA.Enable = 'off';
            app.bLabel = uilabel(app.A1Tab);
            app.bLabel.Enable = 'off';
            app.bLabel.Text = 'b:';
            app.A1EditFieldB = uieditfield(app.A1Tab, 'numeric');
            app.A1EditFieldB.Limits = [0 Inf];
            app.A1EditFieldB.Enable = 'off';
            app.A1UnitDropdownA = uidropdown(app.A1Tab);
            app.A1UnitDropdownA.Items = {'V/L/min', 'V/L/s', 'V/mL/min', 'V/mL/s', 'mV/L/min', 'mV/L/s', 'mV/mL/min', 'mV/mL/s'};
            app.A1UnitDropdownA.Value = 'V/L/min';
            app.A1TypeSelect = uibuttongroup(app.A1Tab);
            app.A1TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @A1TypeSelectSelectionChanged, true);
            app.flowButton = uiradiobutton(app.A1TypeSelect);
            app.flowButton.Text = 'flow';
            app.flowButton.Value = true;
            app.pressureButton = uiradiobutton(app.A1TypeSelect);
            app.pressureButton.Text = 'pressure';
            app.A1UnitDropdownB = uidropdown(app.A1Tab);
            app.A1UnitDropdownB.Items = {'V', 'mV'};
            app.A1UnitDropdownB.Value = 'V';
            app.A2Tab = uitab(app.TabGroupAnalogue);
            app.A2Tab.Title = 'A2';
            app.A2EnableSensor = uicheckbox(app.A2Tab);
            app.A2EnableSensor.ValueChangedFcn = createCallbackFcn(app, @A2EnableSensorValueChanged, true);
            app.A2EnableSensor.Text = 'Enable';
            app.aLabel_2 = uilabel(app.A2Tab);
            app.aLabel_2.Enable = 'off';
            app.aLabel_2.Text = 'a:';
            app.A2EditFieldA = uieditfield(app.A2Tab, 'numeric');
            app.A2EditFieldA.Limits = [0 Inf];
            app.A2EditFieldA.Enable = 'off';
            app.bLabel_2 = uilabel(app.A2Tab);
            app.bLabel_2.Enable = 'off';
            app.bLabel_2.Text = 'b:';
            app.A2EditFieldB = uieditfield(app.A2Tab, 'numeric');
            app.A2EditFieldB.Limits = [0 Inf];
            app.A2EditFieldB.Enable = 'off';
            app.A2TypeSelect = uibuttongroup(app.A2Tab);
            app.A2TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @A2TypeSelectSelectionChanged, true);
            app.flowButton_2 = uiradiobutton(app.A2TypeSelect);
            app.flowButton_2.Text = 'flow';
            app.flowButton_2.Value = true;
            app.pressureButton_2 = uiradiobutton(app.A2TypeSelect);
            app.pressureButton_2.Text = 'pressure';
            app.A2UnitDropdownA = uidropdown(app.A2Tab);
            app.A2UnitDropdownA.Items = {'V/L/min', 'V/L/s', 'V/mL/min', 'V/mL/s', 'mV/L/min', 'mV/L/s', 'mV/mL/min', 'mV/mL/s'};
            app.A2UnitDropdownA.Value = 'V/L/min';
            app.A2UnitDropdownB = uidropdown(app.A2Tab);
            app.A2UnitDropdownB.Items = {'V', 'mV'};
            app.A2UnitDropdownB.Value = 'V';
            app.A3Tab = uitab(app.TabGroupAnalogue);
            app.A3Tab.Title = 'A3';
            app.A3EnableSensor = uicheckbox(app.A3Tab);
            app.A3EnableSensor.ValueChangedFcn = createCallbackFcn(app, @A3EnableSensorValueChanged, true);
            app.A3EnableSensor.Text = 'Enable';
            app.aLabel_3 = uilabel(app.A3Tab);
            app.aLabel_3.Enable = 'off';
            app.aLabel_3.Text = 'a:';
            app.A3EditFieldA = uieditfield(app.A3Tab, 'numeric');
            app.A3EditFieldA.Limits = [0 Inf];
            app.A3EditFieldA.Enable = 'off';
            app.bLabel_3 = uilabel(app.A3Tab);
            app.bLabel_3.Enable = 'off';
            app.bLabel_3.Text = 'b:';
            app.A3EditFieldB = uieditfield(app.A3Tab, 'numeric');
            app.A3EditFieldB.Limits = [0 Inf];
            app.A3EditFieldB.Enable = 'off';
            app.A3TypeSelect = uibuttongroup(app.A3Tab);
            app.A3TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @A3TypeSelectSelectionChanged, true);
            app.flowButton_3 = uiradiobutton(app.A3TypeSelect);
            app.flowButton_3.Text = 'flow';
            app.flowButton_3.Value = true;
            app.pressureButton_3 = uiradiobutton(app.A3TypeSelect);
            app.pressureButton_3.Text = 'pressure';
            app.A3UnitDropdownA = uidropdown(app.A3Tab);
            app.A3UnitDropdownA.Items = {'V/L/min', 'V/L/s', 'V/mL/min', 'V/mL/s', 'mV/L/min', 'mV/L/s', 'mV/mL/min', 'mV/mL/s'};
            app.A3UnitDropdownA.Value = 'V/L/min';
            app.A3UnitDropdownB = uidropdown(app.A3Tab);
            app.A3UnitDropdownB.Items = {'V', 'mV'};
            app.A3UnitDropdownB.Value = 'V';
            app.A4Tab = uitab(app.TabGroupAnalogue);
            app.A4Tab.Title = 'A4';
            app.A4EnableSensor = uicheckbox(app.A4Tab);
            app.A4EnableSensor.ValueChangedFcn = createCallbackFcn(app, @A4EnableSensorValueChanged, true);
            app.A4EnableSensor.Text = 'Enable';
            app.aLabel_4 = uilabel(app.A4Tab);
            app.aLabel_4.Enable = 'off';
            app.aLabel_4.Text = 'a:';
            app.A4EditFieldA = uieditfield(app.A4Tab, 'numeric');
            app.A4EditFieldA.Limits = [0 Inf];
            app.A4EditFieldA.Enable = 'off';
            app.bLabel_4 = uilabel(app.A4Tab);
            app.bLabel_4.Enable = 'off';
            app.bLabel_4.Text = 'b:';
            app.A4EditFieldB = uieditfield(app.A4Tab, 'numeric');
            app.A4EditFieldB.Limits = [0 Inf];
            app.A4EditFieldB.Enable = 'off';
            app.A4TypeSelect = uibuttongroup(app.A4Tab);
            app.A4TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @A4TypeSelectSelectionChanged, true);
            app.flowButton_4 = uiradiobutton(app.A4TypeSelect);
            app.flowButton_4.Text = 'flow';
            app.flowButton_4.Value = true;
            app.pressureButton_4 = uiradiobutton(app.A4TypeSelect);
            app.pressureButton_4.Text = 'pressure';
            app.A4UnitDropdownA = uidropdown(app.A4Tab);
            app.A4UnitDropdownA.Items = {'V/L/min', 'V/L/s', 'V/mL/min', 'V/mL/s', 'mV/L/min', 'mV/L/s', 'mV/mL/min', 'mV/mL/s'};
            app.A4UnitDropdownA.Value = 'V/L/min';
            app.A4UnitDropdownB = uidropdown(app.A4Tab);
            app.A4UnitDropdownB.Items = {'V', 'mV'};
            app.A4UnitDropdownB.Value = 'V';
            app.A5Tab = uitab(app.TabGroupAnalogue);
            app.A5Tab.Title = 'A5';
            app.A5EnableSensor = uicheckbox(app.A5Tab);
            app.A5EnableSensor.ValueChangedFcn = createCallbackFcn(app, @A5EnableSensorValueChanged, true);
            app.A5EnableSensor.Text = 'Enable';
            app.aLabel_5 = uilabel(app.A5Tab);
            app.aLabel_5.Enable = 'off';
            app.aLabel_5.Text = 'a:';
            app.A5EditFieldA = uieditfield(app.A5Tab, 'numeric');
            app.A5EditFieldA.Limits = [0 Inf];
            app.A5EditFieldA.Enable = 'off';
            app.bLabel_5 = uilabel(app.A5Tab);
            app.bLabel_5.Enable = 'off';
            app.bLabel_5.Text = 'b:';
            app.A5EditFieldB = uieditfield(app.A5Tab, 'numeric');
            app.A5EditFieldB.Limits = [0 Inf];
            app.A5EditFieldB.Enable = 'off';
            app.A5TypeSelect = uibuttongroup(app.A5Tab);
            app.A5TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @A5TypeSelectSelectionChanged, true);
            app.flowButton_5 = uiradiobutton(app.A5TypeSelect);
            app.flowButton_5.Text = 'flow';
            app.flowButton_5.Value = true;
            app.pressureButton_5 = uiradiobutton(app.A5TypeSelect);
            app.pressureButton_5.Text = 'pressure';
            app.A5UnitDropdownA = uidropdown(app.A5Tab);
            app.A5UnitDropdownA.Items = {'V/L/min', 'V/L/s', 'V/mL/min', 'V/mL/s', 'mV/L/min', 'mV/L/s', 'mV/mL/min', 'mV/mL/s'};
            app.A5UnitDropdownA.Value = 'V/L/min';
            app.A5UnitDropdownB = uidropdown(app.A5Tab);
            app.A5UnitDropdownB.Items = {'V', 'mV'};
            app.A5UnitDropdownB.Value = 'V';
            app.SelectdeviceLabel = uilabel(app.UIFigure);
            app.SelectdeviceLabel.Text = 'Select device:';
            app.SelectCOMPort = uidropdown(app.UIFigure);
            app.SelectCOMPort.Items = {'No devices'};
            app.SelectCOMPort.Value = 'No devices';
            app.RefreshCOMPorts = uibutton(app.UIFigure, 'push');
            app.RefreshCOMPorts.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMPortsButtonPushed, true);
            app.RefreshCOMPorts.Text = 'Refresh';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.flowGraph = uiaxes(app.UIFigure);
            title(app.flowGraph, 'Flow')
            xlabel(app.flowGraph, 't')
            ylabel(app.flowGraph, 'L/min')
            app.pressureGraph = uiaxes(app.UIFigure);
            title(app.pressureGraph, 'Pressure')
            xlabel(app.pressureGraph, 't')
            ylabel(app.pressureGraph, 'mmHg')
            app.TabGroupDigital = uitabgroup(app.UIFigure);
            app.D1Tab = uitab(app.TabGroupDigital);
            app.D1Tab.Title = 'D1';
            app.D1EnableSensor = uicheckbox(app.D1Tab);
            app.D1EnableSensor.ValueChangedFcn = createCallbackFcn(app, @D1EnableSensorValueChanged, true);
            app.D1EnableSensor.Text = 'Enable';
            app.OutputLabel_7 = uilabel(app.D1Tab);
            app.OutputLabel_7.Enable = 'off';
            app.OutputLabel_7.Text = 'Output:';
            app.D1EditFieldOutput = uieditfield(app.D1Tab, 'numeric');
            app.D1EditFieldOutput.Limits = [0 Inf];
            app.D1EditFieldOutput.Enable = 'off';
            app.D1TypeSelect = uibuttongroup(app.D1Tab);
            app.D1TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @D1TypeSelectSelectionChanged, true);
            app.flowButton_6 = uiradiobutton(app.D1TypeSelect);
            app.flowButton_6.Text = 'flow';
            app.flowButton_6.Value = true;
            app.pressureButton_6 = uiradiobutton(app.D1TypeSelect);
            app.pressureButton_6.Text = 'pressure';
            app.D1UnitDropdownOutput = uidropdown(app.D1Tab);
            app.D1UnitDropdownOutput.Items = {'P/L/min', 'P/L/s', 'P/mL/min', 'P/mL/s'};
            app.D1UnitDropdownOutput.Value = 'P/L/min';
            app.D2Tab = uitab(app.TabGroupDigital);
            app.D2Tab.Title = 'D2';
            app.D2EnableSensor = uicheckbox(app.D2Tab);
            app.D2EnableSensor.ValueChangedFcn = createCallbackFcn(app, @D2EnableSensorValueChanged, true);
            app.D2EnableSensor.Text = 'Enable';
            app.OutputLabel_12 = uilabel(app.D2Tab);
            app.OutputLabel_12.Enable = 'off';
            app.OutputLabel_12.Text = 'Output:';
            app.D2EditFieldOutput = uieditfield(app.D2Tab, 'numeric');
            app.D2EditFieldOutput.Limits = [0 Inf];
            app.D2EditFieldOutput.Enable = 'off';
            app.D2TypeSelect = uibuttongroup(app.D2Tab);
            app.D2TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @D2TypeSelectSelectionChanged, true);
            app.flowButton_7 = uiradiobutton(app.D2TypeSelect);
            app.flowButton_7.Text = 'flow';
            app.flowButton_7.Value = true;
            app.pressureButton_7 = uiradiobutton(app.D2TypeSelect);
            app.pressureButton_7.Text = 'pressure';
            app.D2UnitDropdownOutput = uidropdown(app.D2Tab);
            app.D2UnitDropdownOutput.Items = {'P/L/min', 'P/L/s', 'P/mL/min', 'P/mL/s'};
            app.D2UnitDropdownOutput.Value = 'P/L/min';
            app.D3Tab = uitab(app.TabGroupDigital);
            app.D3Tab.Title = 'D3';
            app.D3EnableSensor = uicheckbox(app.D3Tab);
            app.D3EnableSensor.ValueChangedFcn = createCallbackFcn(app, @D3EnableSensorValueChanged, true);
            app.D3EnableSensor.Text = 'Enable';
            app.OutputLabel_13 = uilabel(app.D3Tab);
            app.OutputLabel_13.Enable = 'off';
            app.OutputLabel_13.Text = 'Output:';
            app.D3EditFieldOutput = uieditfield(app.D3Tab, 'numeric');
            app.D3EditFieldOutput.Limits = [0 Inf];
            app.D3EditFieldOutput.Enable = 'off';
            app.D3TypeSelect = uibuttongroup(app.D3Tab);
            app.D3TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @D3TypeSelectSelectionChanged, true);
            app.flowButton_8 = uiradiobutton(app.D3TypeSelect);
            app.flowButton_8.Text = 'flow';
            app.flowButton_8.Value = true;
            app.pressureButton_8 = uiradiobutton(app.D3TypeSelect);
            app.pressureButton_8.Text = 'pressure';
            app.D3UnitDropdownOutput = uidropdown(app.D3Tab);
            app.D3UnitDropdownOutput.Items = {'P/L/min', 'P/L/s', 'P/mL/min', 'P/mL/s'};
            app.D3UnitDropdownOutput.Value = 'P/L/min';
            app.D4Tab = uitab(app.TabGroupDigital);
            app.D4Tab.Title = 'D4';
            app.D4EnableSensor = uicheckbox(app.D4Tab);
            app.D4EnableSensor.ValueChangedFcn = createCallbackFcn(app, @D4EnableSensorValueChanged, true);
            app.D4EnableSensor.Text = 'Enable';
            app.OutputLabel_14 = uilabel(app.D4Tab);
            app.OutputLabel_14.Enable = 'off';
            app.OutputLabel_14.Text = 'Output:';
            app.D4EditFieldOutput = uieditfield(app.D4Tab, 'numeric');
            app.D4EditFieldOutput.Limits = [0 Inf];
            app.D4EditFieldOutput.Enable = 'off';
            app.D4TypeSelect = uibuttongroup(app.D4Tab);
            app.D4TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @D4TypeSelectSelectionChanged, true);
            app.flowButton_9 = uiradiobutton(app.D4TypeSelect);
            app.flowButton_9.Text = 'flow';
            app.flowButton_9.Value = true;
            app.pressureButton_9 = uiradiobutton(app.D4TypeSelect);
            app.pressureButton_9.Text = 'pressure';
            app.D4UnitDropdownOutput = uidropdown(app.D4Tab);
            app.D4UnitDropdownOutput.Items = {'P/L/min', 'P/L/s', 'P/mL/min', 'P/mL/s'};
            app.D4UnitDropdownOutput.Value = 'P/L/min';
            app.D5Tab = uitab(app.TabGroupDigital);
            app.D5Tab.Title = 'D5';
            app.D5EnableSensor = uicheckbox(app.D5Tab);
            app.D5EnableSensor.ValueChangedFcn = createCallbackFcn(app, @D5EnableSensorValueChanged, true);
            app.D5EnableSensor.Text = 'Enable';
            app.OutputLabel_15 = uilabel(app.D5Tab);
            app.OutputLabel_15.Enable = 'off';
            app.OutputLabel_15.Text = 'Output:';
            app.D5EditFieldOutput = uieditfield(app.D5Tab, 'numeric');
            app.D5EditFieldOutput.Limits = [0 Inf];
            app.D5EditFieldOutput.Enable = 'off';
            app.D5TypeSelect = uibuttongroup(app.D5Tab);
            app.D5TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @D5TypeSelectSelectionChanged, true);
            app.flowButton_10 = uiradiobutton(app.D5TypeSelect);
            app.flowButton_10.Text = 'flow';
            app.flowButton_10.Value = true;
            app.pressureButton_10 = uiradiobutton(app.D5TypeSelect);
            app.pressureButton_10.Text = 'pressure';
            app.D5UnitDropdownOutput = uidropdown(app.D5Tab);
            app.D5UnitDropdownOutput.Items = {'P/L/min', 'P/L/s', 'P/mL/min', 'P/mL/s'};
            app.D5UnitDropdownOutput.Value = 'P/L/min';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.P1Tab = uitab(app.TabGroup);
            app.P1Tab.Title = 'P1';
            app.P1RateUnitsLabel = uilabel(app.P1Tab);
            app.P1RateUnitsLabel.Text = 'L/min';
            app.FeedbackLabel_4 = uilabel(app.P1Tab);
            app.FeedbackLabel_4.Text = 'Feedback:';
            app.P1FeedbackSensor = uidropdown(app.P1Tab);
            app.P1FeedbackSensor.Items = {'Please select'};
            app.P1FeedbackSensor.ValueChangedFcn = createCallbackFcn(app, @P1FeedbackSensorValueChanged, true);
            app.P1FeedbackSensor.Value = 'Please select';
            app.PumprateSpinnerLabel = uilabel(app.P1Tab);
            app.PumprateSpinnerLabel.Text = 'Pump rate:';
            app.P1RateEditField = uispinner(app.P1Tab);
            app.P1RateEditField.Step = 0.1;
            app.P1RateEditField.Limits = [0 Inf];
            app.P1UpdateButton = uibutton(app.P1Tab, 'push');
            app.P1UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @P1UpdateButtonPushed, true);
            app.P1UpdateButton.Text = 'Update pump rate';
            app.P2Tab = uitab(app.TabGroup);
            app.P2Tab.Title = 'P2';
            app.P2RateUnitsLabel = uilabel(app.P2Tab);
            app.P2RateUnitsLabel.Text = 'L/min';
            app.FeedbackLabel_3 = uilabel(app.P2Tab);
            app.FeedbackLabel_3.Text = 'Feedback:';
            app.P2FeedbackSensor = uidropdown(app.P2Tab);
            app.P2FeedbackSensor.Items = {'Please select'};
            app.P2FeedbackSensor.ValueChangedFcn = createCallbackFcn(app, @P2FeedbackSensorValueChanged, true);
            app.P2FeedbackSensor.Value = 'Please select';
            app.PumprateSpinnerLabel_2 = uilabel(app.P2Tab);
            app.PumprateSpinnerLabel_2.Text = 'Pump rate:';
            app.P2RateEditField = uispinner(app.P2Tab);
            app.P2RateEditField.Step = 0.1;
            app.P2RateEditField.Limits = [0 Inf];
            app.P2UpdateButton = uibutton(app.P2Tab, 'push');
            app.P2UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @P2UpdateButtonPushed, true);
            app.P2UpdateButton.Text = 'Update pump rate';
            app.P3Tab = uitab(app.TabGroup);
            app.P3Tab.Title = 'P3';
            app.P3RateUnitsLabel = uilabel(app.P3Tab);
            app.P3RateUnitsLabel.Text = 'L/min';
            app.FeedbackLabel_2 = uilabel(app.P3Tab);
            app.FeedbackLabel_2.Text = 'Feedback:';
            app.P3FeedbackSensor = uidropdown(app.P3Tab);
            app.P3FeedbackSensor.Items = {'Please select'};
            app.P3FeedbackSensor.ValueChangedFcn = createCallbackFcn(app, @P3FeedbackSensorValueChanged, true);
            app.P3FeedbackSensor.Value = 'Please select';
            app.PumprateSpinnerLabel_3 = uilabel(app.P3Tab);
            app.PumprateSpinnerLabel_3.Text = 'Pump rate:';
            app.P3RateEditField = uispinner(app.P3Tab);
            app.P3RateEditField.Step = 0.1;
            app.P3RateEditField.Limits = [0 Inf];
            app.P3UpdateButton = uibutton(app.P3Tab, 'push');
            app.P3UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @P3UpdateButtonPushed, true);
            app.P3UpdateButton.Text = 'Update pump rate';
            app.P4Tab = uitab(app.TabGroup);
            app.P4Tab.Title = 'P4';
            app.P4RateUnitsLabel = uilabel(app.P4Tab);
            app.P4RateUnitsLabel.Text = 'L/min';
            app.FeedbackLabel = uilabel(app.P4Tab);
            app.FeedbackLabel.Text = 'Feedback:';
            app.P4FeedbackSensor = uidropdown(app.P4Tab);
            app.P4FeedbackSensor.Items = {'Please select'};
            app.P4FeedbackSensor.ValueChangedFcn = createCallbackFcn(app, @P4FeedbackSensorValueChanged, true);
            app.P4FeedbackSensor.Value = 'Please select';
            app.PumprateSpinnerLabel_4 = uilabel(app.P4Tab);
            app.PumprateSpinnerLabel_4.Text = 'Pump rate:';
            app.P4RateEditField = uispinner(app.P4Tab);
            app.P4RateEditField.Step = 0.1;
            app.P4RateEditField.Limits = [0 Inf];
            app.P4UpdateButton = uibutton(app.P4Tab, 'push');
            app.P4UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @P4UpdateButtonPushed, true);
            app.P4UpdateButton.Text = 'Update pump rate';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'sensorData';
            app.RenameButton = uibutton(app.UIFigure, 'push');
            app.RenameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameButtonPushed, true);
            app.RenameButton.Text = 'Rename last';
            app.LegendA1CheckBox = uicheckbox(app.UIFigure);
            app.LegendA1CheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendA1CheckBoxValueChanged, true);
            app.LegendA1CheckBox.Text = 'A1';
            app.LegendA1CheckBox.Value = true;
            app.LegendA1Colour = uibutton(app.UIFigure, 'push');
            app.LegendA1Colour.Text = '';
            app.LegendA2Colour = uibutton(app.UIFigure, 'push');
            app.LegendA2Colour.Text = '';
            app.LegendA2CheckBox = uicheckbox(app.UIFigure);
            app.LegendA2CheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendA2CheckBoxValueChanged, true);
            app.LegendA2CheckBox.Text = 'A2';
            app.LegendA2CheckBox.Value = true;
            app.LegendA3Colour = uibutton(app.UIFigure, 'push');
            app.LegendA3Colour.Text = '';
            app.LegendA3CheckBox = uicheckbox(app.UIFigure);
            app.LegendA3CheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendA3CheckBoxValueChanged, true);
            app.LegendA3CheckBox.Text = 'A3';
            app.LegendA3CheckBox.Value = true;
            app.LegendA4Colour = uibutton(app.UIFigure, 'push');
            app.LegendA4Colour.Text = '';
            app.LegendA4CheckBox = uicheckbox(app.UIFigure);
            app.LegendA4CheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendA4CheckBoxValueChanged, true);
            app.LegendA4CheckBox.Text = 'A4';
            app.LegendA4CheckBox.Value = true;
            app.LegendA5Colour = uibutton(app.UIFigure, 'push');
            app.LegendA5Colour.Text = '';
            app.LegendA5CheckBox = uicheckbox(app.UIFigure);
            app.LegendA5CheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendA5CheckBoxValueChanged, true);
            app.LegendA5CheckBox.Text = 'A5';
            app.LegendA5CheckBox.Value = true;
            app.LegendD1Colour = uibutton(app.UIFigure, 'push');
            app.LegendD1Colour.Text = '';
            app.LegendD1CheckBox = uicheckbox(app.UIFigure);
            app.LegendD1CheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendD1CheckBoxValueChanged, true);
            app.LegendD1CheckBox.Text = 'D1';
            app.LegendD1CheckBox.Value = true;
            app.LegendD2Colour = uibutton(app.UIFigure, 'push');
            app.LegendD2Colour.Text = '';
            app.LegendD2CheckBox = uicheckbox(app.UIFigure);
            app.LegendD2CheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendD2CheckBoxValueChanged, true);
            app.LegendD2CheckBox.Text = 'D2';
            app.LegendD2CheckBox.Value = true;
            app.LegendD3Colour = uibutton(app.UIFigure, 'push');
            app.LegendD3Colour.Text = '';
            app.LegendD3CheckBox = uicheckbox(app.UIFigure);
            app.LegendD3CheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendD3CheckBoxValueChanged, true);
            app.LegendD3CheckBox.Text = 'D3';
            app.LegendD3CheckBox.Value = true;
            app.LegendD4Colour = uibutton(app.UIFigure, 'push');
            app.LegendD4Colour.Text = '';
            app.LegendD4CheckBox = uicheckbox(app.UIFigure);
            app.LegendD4CheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendD4CheckBoxValueChanged, true);
            app.LegendD4CheckBox.Text = 'D4';
            app.LegendD4CheckBox.Value = true;
            app.LegendD5Colour = uibutton(app.UIFigure, 'push');
            app.LegendD5Colour.Text = '';
            app.LegendD5CheckBox = uicheckbox(app.UIFigure);
            app.LegendD5CheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendD5CheckBoxValueChanged, true);
            app.LegendD5CheckBox.Text = 'D5';
            app.LegendD5CheckBox.Value = true;
            app.AutoscaleYSwitchLabel = uilabel(app.UIFigure);
            app.AutoscaleYSwitchLabel.Text = 'Autoscale Y';
            app.FlowAutoscaleYSwitch = uiswitch(app.UIFigure, 'slider');
            app.FlowAutoscaleYSwitch.Items = {'Yes', 'No'};
            app.FlowAutoscaleYSwitch.ValueChangedFcn = createCallbackFcn(app, @FlowAutoscaleYSwitchValueChanged, true);
            app.FlowAutoscaleYSwitch.Value = 'Yes';
            app.LowerlimitEditFieldLabel = uilabel(app.UIFigure);
            app.LowerlimitEditFieldLabel.Enable = 'off';
            app.LowerlimitEditFieldLabel.Text = 'Lower limit';
            app.FlowGraphYLowerLimit = uieditfield(app.UIFigure, 'numeric');
            app.FlowGraphYLowerLimit.ValueChangedFcn = createCallbackFcn(app, @FlowGraphYLowerLimitValueChanged, true);
            app.FlowGraphYLowerLimit.Enable = 'off';
            app.UpperlimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperlimitEditFieldLabel.Enable = 'off';
            app.UpperlimitEditFieldLabel.Text = 'Upper limit';
            app.FlowGraphYUpperLimit = uieditfield(app.UIFigure, 'numeric');
            app.FlowGraphYUpperLimit.ValueChangedFcn = createCallbackFcn(app, @FlowGraphYUpperLimitValueChanged, true);
            app.FlowGraphYUpperLimit.Enable = 'off';
            app.AutoscaleYSwitchLabel_2 = uilabel(app.UIFigure);
            app.AutoscaleYSwitchLabel_2.Text = 'Autoscale Y';
            app.PressureAutoscaleYSwitch = uiswitch(app.UIFigure, 'slider');
            app.PressureAutoscaleYSwitch.Items = {'Yes', 'No'};
            app.PressureAutoscaleYSwitch.ValueChangedFcn = createCallbackFcn(app, @PressureAutoscaleYSwitchValueChanged, true);
            app.PressureAutoscaleYSwitch.Value = 'Yes';
            app.UpperlimitEditFieldLabel_2 = uilabel(app.UIFigure);
            app.UpperlimitEditFieldLabel_2.Enable = 'off';
            app.UpperlimitEditFieldLabel_2.Text = 'Upper limit';
            app.PressureGraphYUpperLimit = uieditfield(app.UIFigure, 'numeric');
            app.PressureGraphYUpperLimit.ValueChangedFcn = createCallbackFcn(app, @PressureGraphYUpperLimitValueChanged, true);
            app.PressureGraphYUpperLimit.Enable = 'off';
            app.LowerlimitEditFieldLabel_2 = uilabel(app.UIFigure);
            app.LowerlimitEditFieldLabel_2.Enable = 'off';
            app.LowerlimitEditFieldLabel_2.Text = 'Lower limit';
            app.PressureGraphYLowerLimit = uieditfield(app.UIFigure, 'numeric');
            app.PressureGraphYLowerLimit.ValueChangedFcn = createCallbackFcn(app, @PressureGraphYLowerLimitValueChanged, true);
            app.PressureGraphYLowerLimit.Enable = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'UtwenteLogo2.png';
            app.Button.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Icon = 'RAMlogo.png';
            app.Button_11.Text = '';
            app.CalibratesensorButton = uibutton(app.UIFigure, 'push');
            app.CalibratesensorButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratesensorButtonPushed, true);
            app.CalibratesensorButton.Text = 'Calibrate sensor';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end